classdef ALP_App_v2_Telemetry_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        SABER_Banner            matlab.ui.control.EditField
        UTC_Background          matlab.ui.control.EditField
        MET_Background          matlab.ui.control.EditField
        UTC_Seconds             matlab.ui.control.Label
        UTC_Minutes_to_Seconds  matlab.ui.control.Label
        UTC_Minutes             matlab.ui.control.Label
        UTC_Hours_to_Minutes    matlab.ui.control.Label
        UTC_Hours               matlab.ui.control.Label
        UTC_Label               matlab.ui.control.Label
        MET_Seconds             matlab.ui.control.Label
        MET_Minutes_to_Seconds  matlab.ui.control.Label
        MET_Minutes             matlab.ui.control.Label
        MET_Hours_to_Minutes    matlab.ui.control.Label
        MET_Hours               matlab.ui.control.Label
        MET_Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        Resolution % Resolution property for defining horizontal and vertical resolutions
        HorizontalResolution % Number of pixels in horizontal direction
        VerticalResolution % Number of pixels in vertical direction
        BannerHeight % Height of Banner
        BannerPosition % Vertical Position of Banner
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initializing Resolution Data
            
            app.Resolution = get(0,'screensize');
            app.HorizontalResolution = app.Resolution(3);
            app.VerticalResolution = app.Resolution(4);
            
            % Calculating Top Banner Position Data
            
            app.BannerHeight = app.VerticalResolution/20;
            app.BannerPosition = app.VerticalResolution-70-app.BannerHeight;
            
            % Setting Top Banner Positions
            
            set(app.UIFigure,'Position',[0 40 app.HorizontalResolution app.VerticalResolution-70]);
            set(app.Image,'Position',[0 app.BannerPosition app.BannerHeight app.BannerHeight ]);
            set(app.SABER_Banner,'Position',[app.BannerHeight app.BannerPosition app.HorizontalResolution*35/48 app.BannerHeight]);
            set(app.UTC_Banner,'Position',[app.BannerHeight+app.HorizontalResolution*35/48 app.BannerPosition app.HorizontalResolution*39/320 app.BannerHeight]);
            set(app.MET_Banner,'Position',[app.BannerHeight+app.HorizontalResolution*35/48+app.HorizontalResolution*39/320 app.BannerPosition app.HorizontalResolution*39/320 app.BannerHeight]);
            set(app.UTC_Label,'Position',[app.BannerHeight+app.HorizontalResolution*35/48 app.BannerPosition app.HorizontalResolution*37/960 app.BannerHeight]);
            set(app.MET_Label,'Position',)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.SABER_Banner = uieditfield(app.UIFigure, 'text');
            app.SABER_Banner.Value = 'SABER Ground Station 1';
            app.UTC_Background = uieditfield(app.UIFigure, 'text');
            app.MET_Background = uieditfield(app.UIFigure, 'text');
            app.UTC_Seconds = uilabel(app.UIFigure);
            app.UTC_Seconds.Text = '00';
            app.UTC_Minutes_to_Seconds = uilabel(app.UIFigure);
            app.UTC_Minutes_to_Seconds.Text = ':';
            app.UTC_Minutes = uilabel(app.UIFigure);
            app.UTC_Minutes.Text = '00';
            app.UTC_Hours_to_Minutes = uilabel(app.UIFigure);
            app.UTC_Hours_to_Minutes.Text = ':';
            app.UTC_Hours = uilabel(app.UIFigure);
            app.UTC_Hours.Text = '00';
            app.UTC_Label = uilabel(app.UIFigure);
            app.UTC_Label.Text = 'UTC';
            app.MET_Seconds = uilabel(app.UIFigure);
            app.MET_Seconds.Text = '00';
            app.MET_Minutes_to_Seconds = uilabel(app.UIFigure);
            app.MET_Minutes_to_Seconds.Text = ':';
            app.MET_Minutes = uilabel(app.UIFigure);
            app.MET_Minutes.Text = '00';
            app.MET_Hours_to_Minutes = uilabel(app.UIFigure);
            app.MET_Hours_to_Minutes.Text = ':';
            app.MET_Hours = uilabel(app.UIFigure);
            app.MET_Hours.Text = '00';
            app.MET_Label = uilabel(app.UIFigure);
            app.MET_Label.Text = 'MET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALP_App_v2_Telemetry_Window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end